classdef disparitySim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        BinauralDisparityDemoLabel  matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        ITDusKnobLabel              matlab.ui.control.Label
        ITDusKnob                   matlab.ui.control.Knob
        ILDdBKnobLabel              matlab.ui.control.Label
        ILDdBKnob                   matlab.ui.control.Knob
        Panel_2                     matlab.ui.container.Panel
        LeveldBKnobLabel            matlab.ui.control.Label
        LeveldBKnob                 matlab.ui.control.Knob
        PlayButton                  matlab.ui.control.StateButton
        Panel_3                     matlab.ui.container.Panel
        SignaltypeButtonGroup       matlab.ui.container.ButtonGroup
        BroadbandnoiseButton        matlab.ui.control.RadioButton
        NarrowbandnoiseButton       matlab.ui.control.RadioButton
        SinusoidButton              matlab.ui.control.RadioButton
        FrequencyHzSliderLabel      matlab.ui.control.Label
        FrequencyHzSlider           matlab.ui.control.Slider
    end

    
    properties (Access = private)
        whiteNoise; % Description
        sineWave;
        isPlay = false; % Description
        fs = 48000; % Description
        aDW;
        signalType = 1; % Description
        gammaFiltBank;
    end
    
    methods (Access = private)
        
        function updateSignalGenerator(app)
            frameLen = 4096*2;
            if app.signalType == 1
                %app.FrequencyHzSlider.Enable = false;
                app.whiteNoise = dsp.ColoredNoise('white',frameLen,1,'BoundedOutput', true);
            elseif app.signalType == 2
                %app.FrequencyHzSlider.Enable = true;
                drawnow;
                app.whiteNoise = dsp.ColoredNoise('white',frameLen,1,'BoundedOutput', true);
                if ~isempty(app.gammaFiltBank)
                    release(app.gammaFiltBank)
                end
                app.gammaFiltBank = gammatoneFilterBank([round(app.FrequencyHzSlider.Value) round(app.FrequencyHzSlider.Value)],1,app.fs);
            elseif app.signalType == 3
                %app.FrequencyHzSlider.Enable = true;
                drawnow;
                if ~isempty(app.sineWave)
                    release(app.sineWave)
                end
                app.sineWave = dsp.SineWave(1,round(app.FrequencyHzSlider.Value),'SamplesPerFrame',frameLen,'SampleRate',app.fs);
            end
        end
        
        function output = applyITD(~,inputSignal,itd_us,fs)
            inputSignal(:,2) = inputSignal;
            
            % round ITD to samples (ints)
            itd_samples = (itd_us/1e6)*fs;
            actualItd_samples = round(itd_samples);
            
            if actualItd_samples == 0
                left = inputSignal(:,1);
                right = inputSignal(:,2);
            elseif actualItd_samples > 0
                delay = zeros(actualItd_samples,1);
                left = [delay; inputSignal(:,1)];
                right = [inputSignal(:,2); delay];
            elseif actualItd_samples < 0
                delay = zeros(abs(actualItd_samples),1);
                left = [inputSignal(:,1); delay];
                right = [delay; inputSignal(:,2)];
            end
            
            % return values
            output = [left, right];
        end
        
        function y = applyILD(~,x, ILD)
            y = x.*(10^(ILD/20));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.aDW = audioDeviceWriter(app.fs, 'Device',"Default", 'SupportVariableSizeInput',true);
            app.FrequencyHzSlider.Value = 1000;
        end

        % Callback function
        function PlayPauseButtonPushed(app, event)
            if app.isPlay
                app.isPlay = false;
            else
                app.isPlay = true;
            end
            

        end

        % Selection changed function: SignaltypeButtonGroup
        function SignaltypeButtonGroupSelectionChanged(app, event)

            if app.BroadbandnoiseButton.Value
                app.signalType = 1;
            elseif app.NarrowbandnoiseButton.Value
                app.signalType = 2;
            elseif app.SinusoidButton.Value
                app.signalType = 3;
            end
            
            updateSignalGenerator(app);
        end

        % Value changed function: FrequencyHzSlider
        function FrequencyHzSliderValueChanged(app, event)
            value = app.FrequencyHzSlider.Value;
            updateSignalGenerator(app);
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            updateSignalGenerator(app);
            if app.PlayButton.Value
                app.PlayButton.BackgroundColor = 'r';
            else
                app.PlayButton.BackgroundColor = [0.96,0.96,0.96];
            end
            app.isPlay = app.PlayButton.Value;
            nStart = 1;
            overlap = [];
            while app.isPlay
                %% get piece of audio
                if app.signalType == 1
                    x = app.whiteNoise();
                elseif app.signalType == 2
                    x = app.whiteNoise();
                    wnAmp = max(abs(x));
                    x = app.gammaFiltBank(x);
                    nbAmp = max(abs(x));
                    x = x*(wnAmp/nbAmp)*0.99;
                    %max(abs(x))
                elseif app.signalType == 3
                    x = app.sineWave();
                end
                drawnow;
                %% level
                x = x*10^(app.LeveldBKnob.Value/10);
                drawnow;
                %% binaural disparity
                clear y
                %ITD
                y = applyITD(app,x, app.ITDusKnob.Value, app.fs);
                %overlap add
                if ~isempty(overlap)
                    y(1:size(overlap,1),:) = y(1:size(overlap,1),:)+overlap;
                end
                overlap = y(length(x)+1:end,:);
                y(length(x)+1:end,:) = [];
                
                %ILD
                if app.ILDdBKnob.Value >= 0 %right
                    y(:,1) = applyILD(app,y(:,1), -1*(abs(app.ILDdBKnob.Value)/2));
                    y(:,2) = applyILD(app,y(:,2), abs(app.ILDdBKnob.Value)/2);
                else %left
                    y(:,1) = applyILD(app,y(:,1), abs(app.ILDdBKnob.Value)/2);
                    y(:,2) = applyILD(app,y(:,2), -1*(abs(app.ILDdBKnob.Value)/2));
                end
                drawnow;
                %% playback
                numUnder = app.aDW(y);
                if numUnder
                    disp(numUnder)
                end
                %% visualization
                nEnd = nStart+length(y)-1;
                t = [nStart:nEnd]/app.fs;
                %t(nStart:nEnd) = [nStart:nEnd]/app.fs;
                %yAll(nStart:nEnd,:) = y;
                plot(app.UIAxes,t,y);
                legend(app.UIAxes,'left','right')
                xlim(app.UIAxes,[t(1) t(end)])
                nStart = nEnd+1;
                drawnow;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'amplitude')
            app.UIAxes.YLim = [-0.2 0.2];
            app.BinauralDisparityDemoLabel = uilabel(app.UIFigure);
            app.BinauralDisparityDemoLabel.Text = 'Binaural Disparity Demo';
            app.Panel = uipanel(app.UIFigure);
            app.ITDusKnobLabel = uilabel(app.Panel);
            app.ITDusKnobLabel.Text = 'ITD [us]';
            app.ITDusKnob = uiknob(app.Panel, 'continuous');
            app.ITDusKnob.Limits = [-800 800];
            app.ITDusKnob.MajorTicks = [-800 -600 -400 -200 0 200 400 600 800];
            app.ILDdBKnobLabel = uilabel(app.Panel);
            app.ILDdBKnobLabel.Text = 'ILD [dB]';
            app.ILDdBKnob = uiknob(app.Panel, 'continuous');
            app.ILDdBKnob.Limits = [-20 20];
            app.Panel_2 = uipanel(app.UIFigure);
            app.LeveldBKnobLabel = uilabel(app.Panel_2);
            app.LeveldBKnobLabel.Text = 'Level [dB]';
            app.LeveldBKnob = uiknob(app.Panel_2, 'continuous');
            app.LeveldBKnob.Limits = [-50 0];
            app.LeveldBKnob.Value = -25;
            app.PlayButton = uibutton(app.Panel_2, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.Panel_3 = uipanel(app.UIFigure);
            app.SignaltypeButtonGroup = uibuttongroup(app.Panel_3);
            app.SignaltypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SignaltypeButtonGroupSelectionChanged, true);
            app.SignaltypeButtonGroup.Title = 'Signal type';
            app.BroadbandnoiseButton = uiradiobutton(app.SignaltypeButtonGroup);
            app.BroadbandnoiseButton.Text = 'Broadband noise';
            app.BroadbandnoiseButton.Value = true;
            app.NarrowbandnoiseButton = uiradiobutton(app.SignaltypeButtonGroup);
            app.NarrowbandnoiseButton.Text = 'Narrowband noise';
            app.SinusoidButton = uiradiobutton(app.SignaltypeButtonGroup);
            app.SinusoidButton.Text = 'Sinusoid';
            app.FrequencyHzSliderLabel = uilabel(app.Panel_3);
            app.FrequencyHzSliderLabel.Enable = 'off';
            app.FrequencyHzSliderLabel.Text = 'Frequency [Hz]';
            app.FrequencyHzSlider = uislider(app.Panel_3);
            app.FrequencyHzSlider.Limits = [100 6000];
            app.FrequencyHzSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzSliderValueChanged, true);
            app.FrequencyHzSlider.Value = 1000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = disparitySim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end